classdef batch_LFP_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileInputMenu                   matlab.ui.container.Menu
        InitializeAnalysisMenu          matlab.ui.container.Menu
        SetanalysisfolderrawPSDMenu     matlab.ui.container.Menu
        SetanalysisfolderprocessedPSDMenu  matlab.ui.container.Menu
        SetmultipleanalysisfoldersMenu  matlab.ui.container.Menu
        ResetrelocatedanalysisfolderMenu  matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        SettimelockedconditionsMenu     matlab.ui.container.Menu
        GetConditiontimesMenu           matlab.ui.container.Menu
        ExcludeExperimentsMenu          matlab.ui.container.Menu
        DeleteAnalysedFilesMenu         matlab.ui.container.Menu
        SummaryPlotsMenu                matlab.ui.container.Menu
        ClosePlotsMenu                  matlab.ui.container.Menu
        SpectogramMenu                  matlab.ui.container.Menu
        SubPlotMenu                     matlab.ui.container.Menu
        IndPlotMenu                     matlab.ui.container.Menu
        PowerspectumMenu                matlab.ui.container.Menu
        PSDAverPlotMenu                 matlab.ui.container.Menu
        PSDSubPlotMenu                  matlab.ui.container.Menu
        PSDIndividualPlotMenu           matlab.ui.container.Menu
        PSDparametersMenu               matlab.ui.container.Menu
        TimeMenu                        matlab.ui.container.Menu
        TimeAverageMenu                 matlab.ui.container.Menu
        IndividualMenu                  matlab.ui.container.Menu
        AverageMenu                     matlab.ui.container.Menu
        BarPlotMenu                     matlab.ui.container.Menu
        DotPlotMenu                     matlab.ui.container.Menu
        BoxPlotMenu                     matlab.ui.container.Menu
        RatioMenu                       matlab.ui.container.Menu
        RatioTimeMenu                   matlab.ui.container.Menu
        RatioAverMenu                   matlab.ui.container.Menu
        MultipleComparisonMenu          matlab.ui.container.Menu
        PSDparametersMenu_2             matlab.ui.container.Menu
        PSDparametersratioMenu          matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        ExcelMenu                       matlab.ui.container.Menu
        PSDParametersmeanMenu           matlab.ui.container.Menu
        PSDParameterstimeMenu           matlab.ui.container.Menu
        RatioMenu_excl                  matlab.ui.container.Menu
        MeanMenu                        matlab.ui.container.Menu
        TimeMenu_2                      matlab.ui.container.Menu
        MATLABMenu                      matlab.ui.container.Menu
        PSDparametersmeanMenu           matlab.ui.container.Menu
        PSDparameterstimeMenu           matlab.ui.container.Menu
        RatioMenu_exp                   matlab.ui.container.Menu
        MeanMenu_2                      matlab.ui.container.Menu
        TimeMenu_3                      matlab.ui.container.Menu
        MatlabstatsMenu                 matlab.ui.container.Menu
        onesamplettestMenu              matlab.ui.container.Menu
        twosamplettestMenu              matlab.ui.container.Menu
        pairedttestMenu                 matlab.ui.container.Menu
        ANOVAMenu                       matlab.ui.container.Menu
        RepeatedMeasuresMenu            matlab.ui.container.Menu
        OneWayAnovaMenu                 matlab.ui.container.Menu
        TwoWayAnovaMenu                 matlab.ui.container.Menu
        CorrelationMenu_2               matlab.ui.container.Menu
        PearsonMenu                     matlab.ui.container.Menu
        SpearmanMenu                    matlab.ui.container.Menu
        RawTracesMenu                   matlab.ui.container.Menu
        ExploreDataMenu                 matlab.ui.container.Menu
        GetFileDurationMenu             matlab.ui.container.Menu
        DownSampleMenu                  matlab.ui.container.Menu
        RESETButton                     matlab.ui.control.Button
        LoadDataPathEditField           matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        ToLabel                         matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        FromEditFieldLabel              matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        RawdatafolderButton             matlab.ui.control.Button
        GetFFTSwitchLabel               matlab.ui.control.Label
        GetFFTSwitch                    matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        PowerSpectralDensityprocessingButton  matlab.ui.control.Button
        EnterConditionsEditFieldLabel   matlab.ui.control.Label
        EnterConditionsEditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        x; % spectral_analysis_batch object
        emg_obj; % emg analysis object
        folder_list;
        emg_dir = '';
        psd_proc_app; % user input app regarding PSD processing
        settings_app; % app for setting analysis properties
    end
    
    methods (Access = public)
        
        function update_set_parameters(app, input_array)
            app.x.set_array = input_array;
            %             app.x.dur = str2num(input_array{1});
            %             app.x.bin_ext = answer{2};
            %             app.x.bin_type = answer{3};
            %             app.x.bin_norm_value = str2num(answer{4});
            
        end
        
        
        function update_psdproc(app, normV, linearV, noiseV, noise_bandV, outlierV,bin_sizeV)
            
            % Store inputs as spectral_analysis_batch object properties
            app.x.norm_var = normV;
            app.x.linear_var = linearV;
            app.x.noise_var = noiseV;
            app.x.noisewidth_var = noise_bandV ;
            app.x.outlier_var = outlierV;
            app.x.bin_size = bin_sizeV;
            
        end
        
    end
    
    methods (Access = private)
        
        function enable_disable_FFT_button(app,enable_var)
            
            if enable_var == 1
                
                app.GetFFTSwitchLabel.Enable = 'on';
                app.GetFFTSwitch.Enable = 'on';
                app.FromEditField.Enable = 'on';
                app.FromEditFieldLabel.Enable = 'on';
                app.ToLabel.Enable = 'on';
                app.ToEditField.Enable = 'on';
                app.HzLabel.Enable = 'on';
                app.GetFFTSwitch.Enable = 'on';
                
            elseif enable_var == 0
                
                app.GetFFTSwitchLabel.Enable = 'off';
                app.GetFFTSwitch.Enable = 'off';
                app.FromEditField.Enable = 'off';
                app.FromEditFieldLabel.Enable = 'off';
                app.ToLabel.Enable = 'off';
                app.ToEditField.Enable = 'off';
                app.HzLabel.Enable = 'off';
                app.GetFFTSwitch.Enable = 'off';
            end
        end
        
        
        function enable_disable_Menu(app,enable_var)
            
            if enable_var == 1
                
                app.SummaryPlotsMenu.Enable = 'on';
                app.MatlabstatsMenu.Enable = 'on';
                app.ExportDataMenu.Enable = 'on';
                app.ExcludeExperimentsMenu.Enable = 'on';
                app.SettimelockedconditionsMenu.Enable = 'on';
                app.GetConditiontimesMenu.Enable = 'on';
                
                app.EnterConditionsEditField.Enable = 'on';
                app.EnterConditionsEditFieldLabel.Enable = 'on';


            elseif enable_var == 0
                
                app.SummaryPlotsMenu.Enable = 'off';
                app.MatlabstatsMenu.Enable = 'off';
                app.ExportDataMenu.Enable = 'off';
                app.ExcludeExperimentsMenu.Enable = 'off';
                app.SettimelockedconditionsMenu.Enable = 'off';
                app.GetConditiontimesMenu.Enable = 'off';
                
                app.EnterConditionsEditField.Enable = 'off';
                app.EnterConditionsEditFieldLabel.Enable = 'off';
                
            end
            
        end
        
        function time_locked_separation(app)
            % get file length
            [colnames, the_list,~] = mat_table(app.x);
            f = figure();
            uitable(f, 'Data', the_list, 'ColumnName', colnames,'Units', 'Normalized', 'Position',[0.1, 0.1, 0.8, 0.8]);
            
            % wait for user to close the table
            uiwait(f);
            
            % get user input
            prompt = {'Conditions (separated by ;) :',' Condition duration - mins (space separated) :'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'base;etoh','-5 -0 0 8'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            if isempty(answer) == 0 % proceed only if user clicks ok
                app.x.condition_id = strsplit(answer{1},';');
                app.x.condition_time = str2num(answer{2});
                
                file_split_by_time(app.x);
                app.StatusEditField.Value = 'Data Consistently Separated';
            end


        end
        
    end
    

    methods (Access = private)

        % Value changed function: GetFFTSwitch
        function GetFFTSwitchValueChanged(app, event)
            if strcmp(app.GetFFTSwitch.Value,'on')
                
                % get frequencies
                app.x.LowFCut = app.FromEditField.Value;
                app.x.HighFCut = app.ToEditField.Value;
                
                if strcmp(app.x.set_array.ext, 'mat')
                    
                    % matlab interactive?
                    answer = questdlg('Separate Conditions?','User Input' ,'Yes', 'No', 'Yes');
                    
                    % Handle response
                    switch answer
                        case 'Yes'
                            % get channel structure
                            prompt = {'Low Frequency:','High Frequency:'};
                            Prompt_title = 'For observation only'; dims = [1 80];
                            definput = {'5','80'};
                            answer2 = inputmod(prompt,Prompt_title,dims,definput);
                            
                            % extract power matrix interactive
                            extract_pmatrix_mat_user(app.x,str2double(answer2{1}),str2double(answer2{2}));
                            
                        case 'No'
                            extract_pmatrix_mat(app.x);
                    end
                    
                    % matlab interactive?
                    answer = questdlg('Time-Locked Separation','User Input' ,'Yes', 'No', 'Yes');
                    
                    if strcmp(answer,'Yes')
                        % separate based on time
                        time_locked_separation(app);
                    elseif strcmp(answer,'No')
                        % copy files to raw psd path for psd preprocessing
                        app.x.copy_user_files(app.x.raw_psd_user,app.x.raw_psd_path,'mat');
                    end
                    
                    
                else
                    % long binary file analysis
                    extract_pmatrix_bin(app.x);
                    
                end
                
                
                % Turn on Condition Separation
                app.SettimelockedconditionsMenu.Enable = 'on';
                
                % turn on PSD processing
                app.PowerSpectralDensityprocessingButton.Enable = 'on';
                
                % change lamp color to green
                app.Lamp.Color = [0 1 0];
                
                % update announcement
                app.StatusEditField.Value = 'FFT analysis done.';
                
                % turn off FFT processing
                enable_disable_FFT_button(app,0);
            end
            
        end

        % Button pushed function: PowerSpectralDensityprocessingButton
        function PowerSpectralDensityprocessingButtonPushed(app, event)
            
            % Open the options dialog and pass inputs
            app.psd_proc_app = psd_proc_uinput(app,  app.x.norm_var,  app.x.linear_var, app.x.noise_var, app.x.noisewidth_var, app.x.outlier_var, app.x.bin_size);
            
            % wait for user input
            waitfor(app.psd_proc_app);
            
            if app.x.noisewidth_var ~= -1
                
                % get divisible bin
                if app.x.bin_size ~= -1
                    app.x.bin_size = round(app.x.bin_size/app.x.dur) * app.x.dur;
                end
                
                % Run psd processing and save processed psds
                psd_processing(app.x);
                
                % enable plot and statistics
                enable_disable_Menu(app,1);
                
                % get conditions
                app.EnterConditionsEditField.Value= strjoin(app.x.condition_id,';');
                
                % update announcement
                app.StatusEditField.Value = 'PSD processing completed.';
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.psd_proc_app);
            delete(app.settings_app);
            delete(app);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            enable_disable_FFT_button(app,0);
            app.GetFFTSwitch.Value = 'off';
            app.DeleteAnalysedFilesMenu.Enable = 'off';
            app.MultipleComparisonMenu.Enable = 'off';
            enable_disable_Menu(app,0);
            app.Lamp.Color = [1 0 0];
            app.PowerSpectralDensityprocessingButton.Enable = 'off';
            app.SettimelockedconditionsMenu.Enable = 'off';
            app.StatusEditField.Value = 'Load data or set analysed folder to proceed...';
            app.LoadDataPathEditField.Value = 'C:\';
            clear app.x
        end

        % Menu selected function: SetanalysisfolderrawPSDMenu
        function SetanalysisfolderrawPSDMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % reload saved object
            app.x = reload_object(app.x);
            
            % check if user loaded object
            if app.x == 0
                return
            end
            
            % set path
            app.LoadDataPathEditField.Value = app.x.raw_psd_path;
            % activate psd processing
            app.PowerSpectralDensityprocessingButton.Enable = 'on';
            % activate condition separation
            app.SettimelockedconditionsMenu.Enable = 'on';
            % delete files menu on
            app.DeleteAnalysedFilesMenu.Enable = 'on';
            % update notifications
            app.StatusEditField.Value = 'Raw PSD folder was loaded';
            
        end

        % Menu selected function: SetanalysisfolderprocessedPSDMenu
        function SetanalysisfolderprocessedPSDMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % reload saved object
            app.x = reload_object(app.x);
            
            % check if user loaded object
            if app.x == 0
                return
            end
            
            % activate plot stats menu
            enable_disable_Menu(app,1);
            % delete files menu on
            app.DeleteAnalysedFilesMenu.Enable = 'on';
            % Get conditions
            app.EnterConditionsEditField.Value = strjoin(app.x.condition_id,';');
            % update notifications
            app.StatusEditField.Value = 'Processed PSD folder was loaded';
        end

        % Menu selected function: PSDAverPlotMenu
        function PSDAverPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            if isempty(answer) % check for empty inputs
                return
            end
            
            % plot spectogram
            plot_meanPSD(app.x,str2double(answer{1}),str2double(answer{2}))
        end

        % Menu selected function: PSDSubPlotMenu
        function PSDSubPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            if isempty(answer) % check for empty inputs
                return
            end
            
            % plot spectogram
            plot_subPSD(app.x,str2double(answer{1}),str2double(answer{2}));
        end

        % Menu selected function: ExcludeExperimentsMenu
        function ExcludeExperimentsMenuSelected(app, event)
            % exlude processed PSDs
            exp_exclude(app.x);
        end

        % Menu selected function: PSDIndividualPlotMenu
        function PSDIndividualPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            if isempty(answer) % check for empty inputs
                return
            end
            
            % plot spectogram
            plot_indPSD(app.x,str2double(answer{1}),str2double(answer{2}))
        end

        % Menu selected function: DeleteAnalysedFilesMenu
        function DeleteAnalysedFilesMenuSelected(app, event)
            % delete analysed files
            del_files(app.x);
            
            % update announcement
            app.StatusEditField.Value = 'Files Deleted';
        end

        % Menu selected function: ClosePlotsMenu
        function ClosePlotsMenuSelected(app, event)
            close all;
        end

        % Menu selected function: SubPlotMenu
        function SubPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get channel structure
            prompt = {'Low Frequency:','High Frequency:','Normalise to baseline ( 0 or 1)?'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80', '0'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            if isempty(answer)
                return
            end
            
            % plot spectogram
            spectrogram_subplot(app.x,str2double(answer{1}),str2double(answer{2}),str2double(answer{3}));
            
        end

        % Menu selected function: IndPlotMenu
        function IndPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get channel structure
            prompt = {'Low Frequency:','High Frequency:','Normalise to baseline ( 0 or 1)?'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80','0'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            if isempty(answer) % check for empty inputs
                return
            end
            
            % plot spectogram
            spectrogram_indplot(app.x,str2double(answer{1}),str2double(answer{2}),str2double(answer{3}));
            
        end

        % Menu selected function: SettimelockedconditionsMenu
        function SettimelockedconditionsMenuSelected(app, event)
            % call the time locked separation method
            time_locked_separation(app)
        end

        % Menu selected function: GetConditiontimesMenu
        function GetConditiontimesMenuSelected(app, event)
            % reload object
            s = load(fullfile(app.x.save_path,'psd_object'));
            app.x = copy(s.psd_object);
            
            % get power matrix blocks
            get_cond_times(app.x,app.x.proc_psd_path)
            
            % save psd_object
            psd_object = saveobj(app.x); %#ok
            save(fullfile(app.x.save_path,'psd_object.mat'),'psd_object')
        end

        % Menu selected function: PSDParametersmeanMenu
        function PSDParametersmeanMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a.removeNans = 0; % do not remove nans
            
            % export to excel file
            excel_meanprms(app.x,a,0);
            
        end

        % Menu selected function: SetmultipleanalysisfoldersMenu
        function SetmultipleanalysisfoldersMenuSelected(app, event)
            app.x = spectral_analysis_batch;
            app.folder_list = app.x.obtain_dir_list(app.x.desktop_path);
            if isempty(app.folder_list)
                return
            end
            
            app.SummaryPlotsMenu.Enable = 'on';
            app.SpectogramMenu.Enable = 'off';
            app.PowerspectumMenu.Enable = 'off';
            app.PSDparametersMenu.Enable = 'off';
            app.MultipleComparisonMenu.Enable = 'on';
            app.StatusEditField.Value = 'Multiple analysis folders loaded';
            
        end

        % Callback function: InitializeAnalysisMenu, 
        % RawdatafolderButton
        function InitializeAnalysisMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % Open the options dialog and pass inputs
            app.settings_app = set_parameters(app, app.x.set_array);
            
            % wait for user input
            waitfor(app.settings_app);
            
            % check is structure is empty
            if all(structfun(@isempty, app.x.set_array)) == 1
                return
            end
            
            % set most used parameters to object properties
            app.x.Fs = app.x.set_array.samplerate; % set samplerate
            app.x.dur = app.x.set_array.fft_windowsz; % fft window duration
            
            % pass inputs to class properties
            app.x.channel_struct = strsplit(app.x.set_array.channel_struct,';'); % channel strcture
            app.x.channel_No = find(contains(app.x.channel_struct,app.x.set_array.channel)); % channel to be analyzed
            app.x.Tchannels = length(app.x.channel_struct); % total channel number
            app.x.Nperiods = app.x.set_array.end_time - app.x.set_array.start_time; % total periods to be analyzed
            
            
            % get raw data path
            temp_path = uigetdir(app.x.desktop_path, ['Load ' app.x.set_array.ext ' file directory !!!']);
            if temp_path == 0
                return
            end
            app.x.lfp_data_path =  [temp_path '\'];
            
            % display new path
            app.LoadDataPathEditField.Value = app.x.lfp_data_path;
            
            % update announcement
            app.StatusEditField.Value = [app.x.set_array.ext ' directory loaded'];
            
            % turn on FFT processing
            enable_disable_FFT_button(app,1)
            
        end

        % Menu selected function: TimeAverageMenu
        function TimeAverageMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            psd_prm_time(app.x,a);
        end

        % Menu selected function: IndividualMenu
        function IndividualMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            psd_prm_time_ind(app.x,a);
        end

        % Menu selected function: BarPlotMenu
        function BarPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            aver_psd_prm(app.x,a,2); % 2 for bar plot
        end

        % Menu selected function: DotPlotMenu
        function DotPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            aver_psd_prm(app.x,a,1); % 1 for dot plot
        end

        % Menu selected function: BoxPlotMenu
        function BoxPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            aver_psd_prm(app.x,a,3); % 3 for box plot
        end

        % Menu selected function: RatioTimeMenu
        function RatioTimeMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt_ratio();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.band1 = answer{1};
            a.band2 = answer{2};
            a.band_width = answer{3};
            a.par_var = answer{4};
            a.norms_v = answer{5};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{6}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            prm_ratio_time(app.x,a);
        end

        % Menu selected function: RatioAverMenu
        function RatioAverMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt_plot();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.band1 = answer{1};
            a.band2 = answer{2};
            a.band_width = answer{3};
            a.par_var = answer{4};
            a.norms_v = answer{5};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{6}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            aver_psd_prm_ratio(app.x,a,answer{7}); % 2 for bar plot
        end

        % Menu selected function: GetFileDurationMenu
        function GetFileDurationMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % Open the options dialog and pass inputs
            app.settings_app = set_parameters(app, app.x.set_array);
            
            % wait for user input
            waitfor(app.settings_app);
            
            % check is structure is empty
            if all(structfun(@isempty, app.x.set_array)) == 1
                return
            end
            
            % set most used parameters to object properties
            app.x.Fs = app.x.set_array.samplerate; % set samplerate
            app.x.dur = app.x.set_array.fft_windowsz; % fft window duration
            % pass inputs to class properties
            app.x.channel_struct = strsplit(app.x.set_array.channel_struct,';'); % channel strcture
            app.x.channel_No = find(contains(app.x.channel_struct,app.x.set_array.channel)); % channel to be analyzed
            app.x.Tchannels = length(app.x.channel_struct); % total channel number
            app.x.Nperiods = app.x.set_array.end_time - app.x.set_array.start_time; % total periods to be analyzed
            
            % load file
            % get raw data path
            temp_path = uigetdir(app.x.desktop_path, ['Load ' app.x.set_array.ext ' file directory !!!']);
            if temp_path == 0
                return
            end
            app.x.lfp_data_path =  [temp_path '\'];
            
            if strcmp(app.x.set_array.ext,'mat')
                [colnames, the_list,~] = mat_raw_table(app.x);
            else
                [colnames, the_list,~] = bin_table(app.x);
            end
            
            % get exp_length length
            f = figure();
            uitable(f, 'Data', the_list, 'ColumnName', colnames,'Units', 'Normalized', 'Position',[0.1, 0.1, 0.8, 0.8]);
            % clear object
            clear x
        end

        % Menu selected function: ExploreDataMenu
        function ExploreDataMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % Open the options dialog and pass inputs
            app.settings_app = set_parameters(app, app.x.set_array);
            
            % wait for user input
            waitfor(app.settings_app);
            
            % check is structure is empty
            if all(structfun(@isempty, app.x.set_array)) == 1
                return
            end
            
            % channel settings
            app.x.channel_struct = strsplit(app.x.set_array.channel_struct,';'); % channel strcture
            app.x.channel_No = find(contains(app.x.channel_struct,app.x.set_array.channel)); % channel to be analyzed
            app.x.Tchannels = length(app.x.channel_struct); % total channel number
            
            % load file
            [file,path] = uigetfile([app.x.desktop_path '*.' app.x.set_array.ext]);
            
            if file == 0 % check for empty inputs
                return
            end
            
            if strcmp(app.x.set_array.ext,'mat')
                s = load(fullfile(path,file),'data','samplerate','datastart','dataend','com','comtext');
                output = s.data(s.datastart(app.x.channel_No,1): s.dataend(app.x.channel_No,1));
                plot_traces(output,s.samplerate(1),s.com,s.comtext,1);
                clear s
            else
                s = memmapfile(fullfile(path,file),'Format', app.x.set_array.file_format);
                data_start = app.x.set_array.start_time * 60 * 60 * app.x.set_array.samplerate; % convert to samples;
                data_end = app.x.set_array.end_time * 60 * 60 * app.x.set_array.samplerate; % convert to samples;
                output = double(s.Data(data_start * app.x.Tchannels + app.x.channel_No : app.x.Tchannels ...
                    : data_end * app.x.Tchannels));
                output = output/app.x.set_array.norm; % convert to volts
                plot_traces(output,app.x.set_array.samplerate);
            end
            
            % clear object
            clear x;
        end

        % Menu selected function: DownSampleMenu
        function DownSampleMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % load file
            [file_path] = uigetdir(app.x.desktop_path);
            
            if file_path == 0 % check for empty inputs
                return
            end
            
            % get sampling rate
            lfp_dir = dir(fullfile(file_path,'*.mat'));
            load(fullfile(file_path, lfp_dir(1).name),'samplerate')
            
            % get channel structure
            prompt = {'Channel name','New sample rate:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'bla',int2str(samplerate)};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            if isempty(answer) % check for empty inputs
                return
            end
            
            % get down sample rate
            down_rate = samplerate/str2double(answer{2});
            
            % down dample using decimate
            app.x.down_dec_sample(file_path,down_rate,answer{1})
            
        end

        % Menu selected function: ResetrelocatedanalysisfolderMenu
        function ResetrelocatedanalysisfolderMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % load migrated object
            app.x = reset_migrated_obj_path(app.x);
            
            % check if user loaded object
            if app.x == 0
                return
            end
            
            app.StatusEditField.Value = 'Re-located folder paths was reset and processed folder was loaded';
            
            % activate plot stats menu
            enable_disable_Menu(app,1);
            % delete files menu on
            app.DeleteAnalysedFilesMenu.Enable = 'on';
            % Get conditions
            app.EnterConditionsEditField.Value = strjoin(app.x.condition_id,';');
            
        end

        % Menu selected function: PSDParameterstimeMenu
        function PSDParameterstimeMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a.removeNans = 0; % do not remove nans
            
            % export to excel file
            excel_psd_time(app.x,a,0);
        end

        % Menu selected function: PSDparametersmeanMenu
        function PSDparametersmeanMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a.removeNans = 0; % do not remove nans
            
            % export to excel file
            psd_to_mat_aver(app.x,a,0);
        end

        % Menu selected function: PSDparameterstimeMenu
        function PSDparameterstimeMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a.removeNans = 0; % do not remove nans
            
            % export to excel file
            psd_to_mat_time(app.x,a,0);
        end

        % Menu selected function: PSDparametersMenu_2
        function PSDparametersMenu_2Selected(app, event)
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % plot psd parameters over time
            cmp_psd_prm_time(app.x,a,app.folder_list,false);
            
            % plot psd parameter average
            cmp_psd_prm_aver(app.x,a,app.folder_list,false);
        end

        % Menu selected function: PSDparametersratioMenu
        function PSDparametersratioMenuSelected(app, event)
            % get user input
            [answer, canceled]  = app.x.input_prompt_plot();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.band1 = answer{1};
            a.band2 = answer{2};
            a.band_width = answer{3};
            a.par_var = answer{4};
            a.norms_v = answer{5};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{6}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % plot psd parameters over time
            cmp_psd_prm_time(app.x,a,app.folder_list,true);
            
            % plot psd parameter average
            cmp_psd_prm_aver(app.x,a,app.folder_list,true);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileInputMenu = uimenu(app.UIFigure);
            app.FileInputMenu.Text = 'File Input';
            app.InitializeAnalysisMenu = uimenu(app.FileInputMenu);
            app.InitializeAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @InitializeAnalysisMenuSelected, true);
            app.InitializeAnalysisMenu.Text = 'Initialize Analysis';
            app.SetanalysisfolderrawPSDMenu = uimenu(app.FileInputMenu);
            app.SetanalysisfolderrawPSDMenu.MenuSelectedFcn = createCallbackFcn(app, @SetanalysisfolderrawPSDMenuSelected, true);
            app.SetanalysisfolderrawPSDMenu.Text = 'Set analysis folder (raw PSD)';
            app.SetanalysisfolderprocessedPSDMenu = uimenu(app.FileInputMenu);
            app.SetanalysisfolderprocessedPSDMenu.MenuSelectedFcn = createCallbackFcn(app, @SetanalysisfolderprocessedPSDMenuSelected, true);
            app.SetanalysisfolderprocessedPSDMenu.Text = 'Set analysis folder (processed PSD)';
            app.SetmultipleanalysisfoldersMenu = uimenu(app.FileInputMenu);
            app.SetmultipleanalysisfoldersMenu.MenuSelectedFcn = createCallbackFcn(app, @SetmultipleanalysisfoldersMenuSelected, true);
            app.SetmultipleanalysisfoldersMenu.Text = 'Set multiple analysis folders';
            app.ResetrelocatedanalysisfolderMenu = uimenu(app.FileInputMenu);
            app.ResetrelocatedanalysisfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetrelocatedanalysisfolderMenuSelected, true);
            app.ResetrelocatedanalysisfolderMenu.Text = 'Reset re-located analysis folder';
            app.Menu = uimenu(app.FileInputMenu);
            app.Menu.Text = '-----';
            app.SettimelockedconditionsMenu = uimenu(app.FileInputMenu);
            app.SettimelockedconditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettimelockedconditionsMenuSelected, true);
            app.SettimelockedconditionsMenu.Enable = 'off';
            app.SettimelockedconditionsMenu.Text = 'Set time-locked conditions';
            app.GetConditiontimesMenu = uimenu(app.FileInputMenu);
            app.GetConditiontimesMenu.MenuSelectedFcn = createCallbackFcn(app, @GetConditiontimesMenuSelected, true);
            app.GetConditiontimesMenu.Enable = 'off';
            app.GetConditiontimesMenu.Text = 'Get Condition times';
            app.ExcludeExperimentsMenu = uimenu(app.FileInputMenu);
            app.ExcludeExperimentsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcludeExperimentsMenuSelected, true);
            app.ExcludeExperimentsMenu.Enable = 'off';
            app.ExcludeExperimentsMenu.Text = 'Exclude Experiments';
            app.DeleteAnalysedFilesMenu = uimenu(app.FileInputMenu);
            app.DeleteAnalysedFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteAnalysedFilesMenuSelected, true);
            app.DeleteAnalysedFilesMenu.Enable = 'off';
            app.DeleteAnalysedFilesMenu.Text = 'Delete Analysed Files';
            app.SummaryPlotsMenu = uimenu(app.UIFigure);
            app.SummaryPlotsMenu.Enable = 'off';
            app.SummaryPlotsMenu.Text = 'Summary Plots';
            app.ClosePlotsMenu = uimenu(app.SummaryPlotsMenu);
            app.ClosePlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosePlotsMenuSelected, true);
            app.ClosePlotsMenu.Text = 'Close Plots';
            app.SpectogramMenu = uimenu(app.SummaryPlotsMenu);
            app.SpectogramMenu.Text = 'Spectogram';
            app.SubPlotMenu = uimenu(app.SpectogramMenu);
            app.SubPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @SubPlotMenuSelected, true);
            app.SubPlotMenu.Text = 'SubPlot';
            app.IndPlotMenu = uimenu(app.SpectogramMenu);
            app.IndPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @IndPlotMenuSelected, true);
            app.IndPlotMenu.Text = 'IndPlot';
            app.PowerspectumMenu = uimenu(app.SummaryPlotsMenu);
            app.PowerspectumMenu.Text = 'Power spectum';
            app.PSDAverPlotMenu = uimenu(app.PowerspectumMenu);
            app.PSDAverPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDAverPlotMenuSelected, true);
            app.PSDAverPlotMenu.Text = 'PSD Aver Plot';
            app.PSDSubPlotMenu = uimenu(app.PowerspectumMenu);
            app.PSDSubPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDSubPlotMenuSelected, true);
            app.PSDSubPlotMenu.Text = 'PSD Sub Plot';
            app.PSDIndividualPlotMenu = uimenu(app.PowerspectumMenu);
            app.PSDIndividualPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDIndividualPlotMenuSelected, true);
            app.PSDIndividualPlotMenu.Text = 'PSD Individual Plot';
            app.PSDparametersMenu = uimenu(app.SummaryPlotsMenu);
            app.PSDparametersMenu.Text = 'PSD parameters';
            app.TimeMenu = uimenu(app.PSDparametersMenu);
            app.TimeMenu.Text = 'Time';
            app.TimeAverageMenu = uimenu(app.TimeMenu);
            app.TimeAverageMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeAverageMenuSelected, true);
            app.TimeAverageMenu.Text = 'Average';
            app.IndividualMenu = uimenu(app.TimeMenu);
            app.IndividualMenu.MenuSelectedFcn = createCallbackFcn(app, @IndividualMenuSelected, true);
            app.IndividualMenu.Text = 'Individual';
            app.AverageMenu = uimenu(app.PSDparametersMenu);
            app.AverageMenu.Text = 'Average';
            app.BarPlotMenu = uimenu(app.AverageMenu);
            app.BarPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @BarPlotMenuSelected, true);
            app.BarPlotMenu.Text = 'Bar Plot';
            app.DotPlotMenu = uimenu(app.AverageMenu);
            app.DotPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @DotPlotMenuSelected, true);
            app.DotPlotMenu.Text = 'Dot Plot';
            app.BoxPlotMenu = uimenu(app.AverageMenu);
            app.BoxPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @BoxPlotMenuSelected, true);
            app.BoxPlotMenu.Text = 'Box Plot';
            app.RatioMenu = uimenu(app.PSDparametersMenu);
            app.RatioMenu.Text = 'Ratio';
            app.RatioTimeMenu = uimenu(app.RatioMenu);
            app.RatioTimeMenu.MenuSelectedFcn = createCallbackFcn(app, @RatioTimeMenuSelected, true);
            app.RatioTimeMenu.Text = 'Time';
            app.RatioAverMenu = uimenu(app.RatioMenu);
            app.RatioAverMenu.MenuSelectedFcn = createCallbackFcn(app, @RatioAverMenuSelected, true);
            app.RatioAverMenu.Text = 'Average';
            app.MultipleComparisonMenu = uimenu(app.SummaryPlotsMenu);
            app.MultipleComparisonMenu.Enable = 'off';
            app.MultipleComparisonMenu.Text = 'Multiple Comparison';
            app.PSDparametersMenu_2 = uimenu(app.MultipleComparisonMenu);
            app.PSDparametersMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PSDparametersMenu_2Selected, true);
            app.PSDparametersMenu_2.Text = 'PSD parameters';
            app.PSDparametersratioMenu = uimenu(app.MultipleComparisonMenu);
            app.PSDparametersratioMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDparametersratioMenuSelected, true);
            app.PSDparametersratioMenu.Text = 'PSD parameters ratio';
            app.ExportDataMenu = uimenu(app.UIFigure);
            app.ExportDataMenu.Enable = 'off';
            app.ExportDataMenu.Text = 'Export Data';
            app.ExcelMenu = uimenu(app.ExportDataMenu);
            app.ExcelMenu.Text = 'Excel';
            app.PSDParametersmeanMenu = uimenu(app.ExcelMenu);
            app.PSDParametersmeanMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDParametersmeanMenuSelected, true);
            app.PSDParametersmeanMenu.Text = 'PSD Parameters - mean';
            app.PSDParameterstimeMenu = uimenu(app.ExcelMenu);
            app.PSDParameterstimeMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDParameterstimeMenuSelected, true);
            app.PSDParameterstimeMenu.Text = 'PSD Parameters - time';
            app.RatioMenu_excl = uimenu(app.ExcelMenu);
            app.RatioMenu_excl.Text = 'Ratio';
            app.MeanMenu = uimenu(app.RatioMenu_excl);
            app.MeanMenu.Text = 'Mean';
            app.TimeMenu_2 = uimenu(app.RatioMenu_excl);
            app.TimeMenu_2.Text = 'Time';
            app.MATLABMenu = uimenu(app.ExportDataMenu);
            app.MATLABMenu.Text = 'MATLAB';
            app.PSDparametersmeanMenu = uimenu(app.MATLABMenu);
            app.PSDparametersmeanMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDparametersmeanMenuSelected, true);
            app.PSDparametersmeanMenu.Text = 'PSD parameters-mean';
            app.PSDparameterstimeMenu = uimenu(app.MATLABMenu);
            app.PSDparameterstimeMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDparameterstimeMenuSelected, true);
            app.PSDparameterstimeMenu.Text = 'PSD parameters - time';
            app.RatioMenu_exp = uimenu(app.MATLABMenu);
            app.RatioMenu_exp.Text = 'Ratio';
            app.MeanMenu_2 = uimenu(app.RatioMenu_exp);
            app.MeanMenu_2.Text = 'Mean';
            app.TimeMenu_3 = uimenu(app.RatioMenu_exp);
            app.TimeMenu_3.Text = 'Time';
            app.MatlabstatsMenu = uimenu(app.UIFigure);
            app.MatlabstatsMenu.Enable = 'off';
            app.MatlabstatsMenu.Text = 'Matlab stats';
            app.onesamplettestMenu = uimenu(app.MatlabstatsMenu);
            app.onesamplettestMenu.Text = 'one-sample t-test';
            app.twosamplettestMenu = uimenu(app.MatlabstatsMenu);
            app.twosamplettestMenu.Text = 'two-sample t-test';
            app.pairedttestMenu = uimenu(app.MatlabstatsMenu);
            app.pairedttestMenu.Text = 'paired t-test';
            app.ANOVAMenu = uimenu(app.MatlabstatsMenu);
            app.ANOVAMenu.Text = 'ANOVA';
            app.RepeatedMeasuresMenu = uimenu(app.ANOVAMenu);
            app.RepeatedMeasuresMenu.Text = 'Repeated Measures';
            app.OneWayAnovaMenu = uimenu(app.ANOVAMenu);
            app.OneWayAnovaMenu.Text = 'One Way Anova';
            app.TwoWayAnovaMenu = uimenu(app.ANOVAMenu);
            app.TwoWayAnovaMenu.Text = 'Two Way Anova';
            app.CorrelationMenu_2 = uimenu(app.MatlabstatsMenu);
            app.CorrelationMenu_2.Text = 'Correlation';
            app.PearsonMenu = uimenu(app.CorrelationMenu_2);
            app.PearsonMenu.Text = 'Pearson';
            app.SpearmanMenu = uimenu(app.CorrelationMenu_2);
            app.SpearmanMenu.Text = 'Spearman';
            app.RawTracesMenu = uimenu(app.UIFigure);
            app.RawTracesMenu.Text = 'Raw Traces';
            app.ExploreDataMenu = uimenu(app.RawTracesMenu);
            app.ExploreDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExploreDataMenuSelected, true);
            app.ExploreDataMenu.Text = 'Explore Data';
            app.GetFileDurationMenu = uimenu(app.RawTracesMenu);
            app.GetFileDurationMenu.MenuSelectedFcn = createCallbackFcn(app, @GetFileDurationMenuSelected, true);
            app.GetFileDurationMenu.Text = 'Get File Duration';
            app.DownSampleMenu = uimenu(app.RawTracesMenu);
            app.DownSampleMenu.MenuSelectedFcn = createCallbackFcn(app, @DownSampleMenuSelected, true);
            app.DownSampleMenu.Text = 'DownSample';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.LoadDataPathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadDataPathEditField.Editable = 'off';
            app.LoadDataPathEditField.Value = 'C:\';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'Load data or set analysed folder to proceed...';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Enable = 'off';
            app.ToLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField.Limits = [0 1000];
            app.ToEditField.Enable = 'off';
            app.ToEditField.Value = 200;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Enable = 'off';
            app.HzLabel.Text = 'Hz';
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Enable = 'off';
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField.Limits = [0 1000];
            app.FromEditField.Enable = 'off';
            app.FromEditField.Value = 0.4;
            app.RawdatafolderButton = uibutton(app.UIFigure, 'push');
            app.RawdatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeAnalysisMenuSelected, true);
            app.RawdatafolderButton.Text = 'Raw data folder';
            app.GetFFTSwitchLabel = uilabel(app.UIFigure);
            app.GetFFTSwitchLabel.Enable = 'off';
            app.GetFFTSwitchLabel.Text = 'Get FFT';
            app.GetFFTSwitch = uiswitch(app.UIFigure, 'slider');
            app.GetFFTSwitch.Items = {'off', 'on'};
            app.GetFFTSwitch.ValueChangedFcn = createCallbackFcn(app, @GetFFTSwitchValueChanged, true);
            app.GetFFTSwitch.Enable = 'off';
            app.GetFFTSwitch.Value = 'off';
            app.Lamp = uilamp(app.UIFigure);
            app.PowerSpectralDensityprocessingButton = uibutton(app.UIFigure, 'push');
            app.PowerSpectralDensityprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectralDensityprocessingButtonPushed, true);
            app.PowerSpectralDensityprocessingButton.Enable = 'off';
            app.PowerSpectralDensityprocessingButton.Text = 'Power Spectral Density processing...';
            app.EnterConditionsEditFieldLabel = uilabel(app.UIFigure);
            app.EnterConditionsEditFieldLabel.Enable = 'off';
            app.EnterConditionsEditFieldLabel.Text = 'Enter Conditions';
            app.EnterConditionsEditField = uieditfield(app.UIFigure, 'text');
            app.EnterConditionsEditField.Enable = 'off';
            app.EnterConditionsEditField.Value = 'pre;post';
        end
    end

    methods (Access = public)

        % Construct app
        function app = batch_LFP_analysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end