classdef psd_proc_uinput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnterButton                 matlab.ui.control.Button
        ExitButton                  matlab.ui.control.Button
        NormalizeLabel              matlab.ui.control.Label
        NormalizeDropDown           matlab.ui.control.DropDown
        RemoveOutliersLabel         matlab.ui.control.Label
        RemoveOutliersDropDown      matlab.ui.control.DropDown
        XMedianLabel                matlab.ui.control.Label
        OutlierEditField            matlab.ui.control.NumericEditField
        LinearizeLabel              matlab.ui.control.Label
        LinearizeDropDown           matlab.ui.control.DropDown
        RemoveNoiseDropDownLabel    matlab.ui.control.Label
        RemoveNoiseDropDown         matlab.ui.control.DropDown
        BandFreqEditFieldLabel      matlab.ui.control.Label
        BandFreqEditField           matlab.ui.control.NumericEditField
        BandWidthEditFieldLabel     matlab.ui.control.Label
        BandWidthEditField          matlab.ui.control.NumericEditField
        HzLabel                     matlab.ui.control.Label
        HzLabel_2                   matlab.ui.control.Label
        ChangeBinSizeDropDownLabel  matlab.ui.control.Label
        ChangeBinSizeDropDown       matlab.ui.control.DropDown
        NewBinSizeEditFieldLabel    matlab.ui.control.Label
        NewBinSizeEditField         matlab.ui.control.NumericEditField
        SecondsLabel                matlab.ui.control.Label
        PSDANALYSISUSERINPUTLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp  % Main app
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, batch_LFP_analysis, normV, linearV, noiseV, noise_widthV, outlierV, bin_sizeV)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = batch_LFP_analysis;
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            % Send values back to main window by calling its public function
            update_psdproc(app.CallingApp, app.NormalizeDropDown.Value, app.LinearizeDropDown.Value...
                ,app.BandFreqEditField.Value, app.BandWidthEditField.Value ,app.OutlierEditField.Value,app.NewBinSizeEditField.Value);
            
            % delete app
            delete(app)
                        
        end

        % Value changed function: RemoveOutliersDropDown
        function RemoveOutliersDropDownValueChanged(app, event)
            if strcmp(app.RemoveOutliersDropDown.Value,'yes')
                
                % set initial value
                app.OutlierEditField.Value = 5;
                
                app.XMedianLabel.Enable = 'on';
                app.OutlierEditField.Enable = 'on';
                app.OutlierEditField.Editable = 'on';
                
            elseif strcmp(app.RemoveOutliersDropDown.Value,'no')
                
                app.XMedianLabel.Enable = 'off';
                app.OutlierEditField.Enable = 'off';
                app.OutlierEditField.Editable = 'off';
                
                % set value to -1 to indicate false
                app.OutlierEditField.Value = -1;
            end
            
        end

        % Value changed function: RemoveNoiseDropDown
        function RemoveNoiseDropDownValueChanged(app, event)
            if strcmp(app.RemoveNoiseDropDown.Value,'yes')
                
                % set initial value
                app.BandFreqEditField.Value = 60;
                app.BandWidthEditField.Value = 1;
                
                app.BandFreqEditField.Enable = 'on';
                app.BandWidthEditField.Enable = 'on';
                app.BandFreqEditField.Editable = 'on';
                app.BandWidthEditField.Editable = 'on';
                
                app.BandFreqEditFieldLabel.Enable = 'on';
                app.BandWidthEditFieldLabel.Enable = 'on';
                
                app.HzLabel.Enable = 'on';
                app.HzLabel_2.Enable = 'on';
                
            elseif strcmp(app.RemoveNoiseDropDown.Value,'no')
                
                app.BandFreqEditField.Enable = 'off';
                app.BandWidthEditField.Enable = 'off';
                app.BandFreqEditField.Editable = 'off';
                app.BandWidthEditField.Editable = 'off';
                
                app.BandFreqEditFieldLabel.Enable = 'off';
                app.BandWidthEditFieldLabel.Enable = 'off';
                
                app.HzLabel.Enable = 'off';
                app.HzLabel_2.Enable = 'off';
                
                % set value to -1 to indicate false
                app.BandFreqEditField.Value = -1;
            end
        end

        % Value changed function: ChangeBinSizeDropDown
        function ChangeBinSizeDropDownValueChanged(app, event)
            
            if strcmp(app.ChangeBinSizeDropDown.Value,'yes')
                
                % set value to -1 to default
                app.NewBinSizeEditField.Value = 300;
                
                app.NewBinSizeEditField.Enable = 'on';
                app.NewBinSizeEditField.Editable = 'on';
                app.NewBinSizeEditFieldLabel.Enable = 'on';
                app.SecondsLabel.Enable = 'on';
                
            elseif strcmp(app.ChangeBinSizeDropDown.Value,'no')
                
                app.NewBinSizeEditField.Enable = 'off';
                app.NewBinSizeEditField.Editable = 'off';
                app.NewBinSizeEditFieldLabel.Enable = 'off';
                app.SecondsLabel.Enable = 'off';
                
                % set value to -1 to indicate false
                app.NewBinSizeEditField.Value = -1;
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.BandWidthEditField.Limits = [-1,1000];
            app.BandWidthEditField.Value = -1;
            
            % Send values back to main window by calling its public function
            update_psdproc(app.CallingApp, app.NormalizeDropDown.Value, app.LinearizeDropDown.Value...
                ,app.BandFreqEditField.Value, app.BandWidthEditField.Value ,app.OutlierEditField.Value,app.NewBinSizeEditField.Value);
            
            % delete app
            delete(app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.NormalizeLabel = uilabel(app.UIFigure);
            app.NormalizeLabel.Text = 'Normalize ?';
            app.NormalizeDropDown = uidropdown(app.UIFigure);
            app.NormalizeDropDown.Items = {'no', 'totalpower', 'log(10)', 'log(e)', 'maxVal'};
            app.NormalizeDropDown.Value = 'no';
            app.RemoveOutliersLabel = uilabel(app.UIFigure);
            app.RemoveOutliersLabel.Text = 'Remove Outliers ?';
            app.RemoveOutliersDropDown = uidropdown(app.UIFigure);
            app.RemoveOutliersDropDown.Items = {'no', 'yes'};
            app.RemoveOutliersDropDown.ValueChangedFcn = createCallbackFcn(app, @RemoveOutliersDropDownValueChanged, true);
            app.RemoveOutliersDropDown.Value = 'no';
            app.XMedianLabel = uilabel(app.UIFigure);
            app.XMedianLabel.Enable = 'off';
            app.XMedianLabel.Text = 'X Median';
            app.OutlierEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutlierEditField.Editable = 'off';
            app.OutlierEditField.Enable = 'off';
            app.OutlierEditField.Value = -1;
            app.LinearizeLabel = uilabel(app.UIFigure);
            app.LinearizeLabel.Text = 'Linearize ?';
            app.LinearizeDropDown = uidropdown(app.UIFigure);
            app.LinearizeDropDown.Items = {'no', 'yes'};
            app.LinearizeDropDown.Value = 'no';
            app.RemoveNoiseDropDownLabel = uilabel(app.UIFigure);
            app.RemoveNoiseDropDownLabel.Text = 'Remove Noise?';
            app.RemoveNoiseDropDown = uidropdown(app.UIFigure);
            app.RemoveNoiseDropDown.Items = {'no', 'yes'};
            app.RemoveNoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @RemoveNoiseDropDownValueChanged, true);
            app.RemoveNoiseDropDown.Value = 'no';
            app.BandFreqEditFieldLabel = uilabel(app.UIFigure);
            app.BandFreqEditFieldLabel.Enable = 'off';
            app.BandFreqEditFieldLabel.Text = 'Band Freq.';
            app.BandFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandFreqEditField.Limits = [-1 1000];
            app.BandFreqEditField.ValueDisplayFormat = '%.0f';
            app.BandFreqEditField.Editable = 'off';
            app.BandFreqEditField.Enable = 'off';
            app.BandFreqEditField.Value = -1;
            app.BandWidthEditFieldLabel = uilabel(app.UIFigure);
            app.BandWidthEditFieldLabel.Enable = 'off';
            app.BandWidthEditFieldLabel.Text = 'Band Width';
            app.BandWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandWidthEditField.Limits = [0 1000];
            app.BandWidthEditField.ValueDisplayFormat = '%.0f';
            app.BandWidthEditField.Editable = 'off';
            app.BandWidthEditField.Enable = 'off';
            app.BandWidthEditField.Value = 1;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Enable = 'off';
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Enable = 'off';
            app.HzLabel_2.Text = 'Hz';
            app.ChangeBinSizeDropDownLabel = uilabel(app.UIFigure);
            app.ChangeBinSizeDropDownLabel.Text = 'Change Bin Size';
            app.ChangeBinSizeDropDown = uidropdown(app.UIFigure);
            app.ChangeBinSizeDropDown.Items = {'no', 'yes'};
            app.ChangeBinSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeBinSizeDropDownValueChanged, true);
            app.ChangeBinSizeDropDown.Value = 'no';
            app.NewBinSizeEditFieldLabel = uilabel(app.UIFigure);
            app.NewBinSizeEditFieldLabel.Enable = 'off';
            app.NewBinSizeEditFieldLabel.Text = 'New Bin Size';
            app.NewBinSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.NewBinSizeEditField.Limits = [-1 10000];
            app.NewBinSizeEditField.ValueDisplayFormat = '%.0f';
            app.NewBinSizeEditField.Editable = 'off';
            app.NewBinSizeEditField.Enable = 'off';
            app.NewBinSizeEditField.Value = -1;
            app.SecondsLabel = uilabel(app.UIFigure);
            app.SecondsLabel.Enable = 'off';
            app.SecondsLabel.Text = 'Seconds';
            app.PSDANALYSISUSERINPUTLabel = uilabel(app.UIFigure);
            app.PSDANALYSISUSERINPUTLabel.Text = 'PSD ANALYSIS - USER INPUT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = psd_proc_uinput(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end