classdef set_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EnterButton                     matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        MatWandParametersLabel          matlab.ui.control.Label
        SizesecEditFieldLabel           matlab.ui.control.Label
        SizesecEditField                matlab.ui.control.NumericEditField
        OtherextbinEditFieldLabel       matlab.ui.control.Label
        OtherextbinEditField            matlab.ui.control.EditField
        ChangePSDpropertiesButton       matlab.ui.control.StateButton
        SampleratesampsecLabel          matlab.ui.control.Label
        SamplerateEditField             matlab.ui.control.NumericEditField
        FileTypeDropDownLabel           matlab.ui.control.Label
        FileTypeDropDown                matlab.ui.control.DropDown
        BinaryFormatEditFieldLabel      matlab.ui.control.Label
        BinaryFormatEditField           matlab.ui.control.EditField
        ChannelStructureEditFieldLabel  matlab.ui.control.Label
        ChannelStructureEditField       matlab.ui.control.EditField
        OverlapLabel                    matlab.ui.control.Label
        OverlapEditField                matlab.ui.control.NumericEditField
        ChannelAnalyzedEditFieldLabel   matlab.ui.control.Label
        ChannelAnalyzedEditField        matlab.ui.control.EditField
        DurationHrsLabel                matlab.ui.control.Label
        Analysis_Duration               matlab.ui.control.NumericEditField
        Analysis_Duration_2             matlab.ui.control.NumericEditField
        NormFactorLabel                 matlab.ui.control.Label
        NormFactorEditField             matlab.ui.control.NumericEditField
        SetsamplerateCheckBox           matlab.ui.control.CheckBox
        WholeFileCheckBox               matlab.ui.control.CheckBox
        WindowTypeEditFieldLabel        matlab.ui.control.Label
        WindowTypeEditField             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        CallingApp % Main app
        file_ext
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, batch_LFP_analysis, input_array)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = batch_LFP_analysis;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            if strcmp(app.file_ext,'other')
                app.file_ext = app.OtherextbinEditField.Value;
            else
                app.file_ext = app.FileTypeDropDown.Value;
            end
            
            input_array.ext = app.file_ext; % file_ext
            input_array.channel_struct = app.ChannelStructureEditField.Value; % Channel structure
            input_array.channel = app.ChannelAnalyzedEditField.Value; % Channel to be analyzed
            
            input_array.file_format =  app.BinaryFormatEditField.Value; % file format
            input_array.norm = app.NormFactorEditField.Value; % normalization factor for values (y)
            input_array.samplerate = app.SamplerateEditField.Value; % sampling rate (samples per second)
            input_array.start_time = app.Analysis_Duration.Value; % start time for file analysis
            input_array.end_time = app.Analysis_Duration_2.Value; % end time for file analysis
            
            input_array.fft_window_type = app.WindowTypeEditField.Value; % spectral window type
            input_array.fft_overlap = app.OverlapEditField.Value; % percentage overlap for fft window segments
            input_array.fft_windowsz = app.SizesecEditField.Value; % duration (seconds) of fft window
            
            % Send values back to main window by calling its public function
            update_set_parameters(app.CallingApp, input_array);
            
            % delete app
            delete(app)
        end

        % Value changed function: ChangePSDpropertiesButton
        function ChangePSDpropertiesButtonValueChanged(app, event)
            % change PSD properties
            if app.ChangePSDpropertiesButton.Value ==1
                app.WindowTypeEditField.Enable = 'on'; app.WindowTypeEditField.Editable = 'on';
%                 app.OverlapEditField.Enable = 'on'; app.OverlapEditField.Editable = 'on';
                app.SizesecEditField.Enable = 'on'; app.SizesecEditField.Editable = 'on';
            else
                app.WindowTypeEditField.Enable = 'off'; app.WindowTypeEditField.Editable = 'off';
                app.OverlapEditField.Enable = 'off'; app.OverlapEditField.Editable = 'off';
                app.SizesecEditField.Enable = 'off'; app.SizesecEditField.Editable = 'off';
            end
            
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            app.file_ext = app.FileTypeDropDown.Value; % file extension
            
            % disable or enable fields
            if strcmp(app.file_ext,'other')
                
                app.Analysis_Duration.Enable = 'on'; app.Analysis_Duration.Editable = 'on';
                app.Analysis_Duration_2.Enable = 'on'; app.Analysis_Duration_2.Editable = 'on';
                app.SamplerateEditField.Enable = 'on'; app.SamplerateEditField.Editable = 'on';
                app.OtherextbinEditField.Enable = 'on';  app.OtherextbinEditField.Editable = 'on';
                app.BinaryFormatEditField.Enable = 'on'; app.BinaryFormatEditField.Editable = 'on';
                app.NormFactorEditField.Enable = 'on'; app.NormFactorEditField.Editable = 'on';
                
                % set default properties  
                app.NormFactorEditField.Value = 1;
                app.BinaryFormatEditField.Value = 'single';
                
            elseif strcmp(app.file_ext,'adibin')
                
                app.SamplerateEditField.Enable = 'on'; app.SamplerateEditField.Editable = 'on';
                app.Analysis_Duration.Enable = 'on'; app.Analysis_Duration.Editable = 'on';
                app.Analysis_Duration_2.Enable = 'on'; app.Analysis_Duration_2.Editable = 'on';
                app.OtherextbinEditField.Enable = 'off';  app.OtherextbinEditField.Editable = 'off';
                app.BinaryFormatEditField.Enable = 'off'; app.BinaryFormatEditField.Editable = 'off';
                app.NormFactorEditField.Enable = 'off'; app.NormFactorEditField.Editable = 'off';
                
            elseif strcmp(app.file_ext,'mat')
                
                app.SamplerateEditField.Enable = 'off'; app.SamplerateEditField.Editable = 'off';
                app.OtherextbinEditField.Enable = 'off';  app.OtherextbinEditField.Editable = 'off';
                app.BinaryFormatEditField.Enable = 'off'; app.BinaryFormatEditField.Editable = 'off';
                app.NormFactorEditField.Enable = 'off'; app.NormFactorEditField.Editable = 'off';
                app.SamplerateEditField.Enable = 'off'; app.SamplerateEditField.Editable = 'off';
                app.Analysis_Duration.Enable = 'off'; app.Analysis_Duration.Editable = 'off';
                app.Analysis_Duration_2.Enable = 'off'; app.Analysis_Duration_2.Editable = 'off';
            end
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            input_array = struct;
            
            % Send values back to main window by calling its public function
            update_set_parameters(app.CallingApp, input_array);
            
            % delete app
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Display message
            uialert(app.UIFigure,'No settings were loaded!!','Error.');
            pause(1) % wait for 1 seconds before deleting app
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.MatWandParametersLabel = uilabel(app.UIFigure);
            app.MatWandParametersLabel.Text = 'MatWand Parameters';
            app.SizesecEditFieldLabel = uilabel(app.UIFigure);
            app.SizesecEditFieldLabel.Text = ' Size (sec)';
            app.SizesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizesecEditField.Limits = [0 10000];
            app.SizesecEditField.ValueDisplayFormat = '%.0f';
            app.SizesecEditField.Enable = 'off';
            app.SizesecEditField.Value = 5;
            app.OtherextbinEditFieldLabel = uilabel(app.UIFigure);
            app.OtherextbinEditFieldLabel.Text = 'Other ext (.bin)';
            app.OtherextbinEditField = uieditfield(app.UIFigure, 'text');
            app.OtherextbinEditField.Editable = 'off';
            app.OtherextbinEditField.Enable = 'off';
            app.OtherextbinEditField.Value = 'adibin';
            app.ChangePSDpropertiesButton = uibutton(app.UIFigure, 'state');
            app.ChangePSDpropertiesButton.ValueChangedFcn = createCallbackFcn(app, @ChangePSDpropertiesButtonValueChanged, true);
            app.ChangePSDpropertiesButton.Text = 'Change PSD properties';
            app.SampleratesampsecLabel = uilabel(app.UIFigure);
            app.SampleratesampsecLabel.Text = 'Samplerate (samp/sec)';
            app.SamplerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplerateEditField.Limits = [0 100000];
            app.SamplerateEditField.ValueDisplayFormat = '%.0f';
            app.SamplerateEditField.Editable = 'off';
            app.SamplerateEditField.Enable = 'off';
            app.SamplerateEditField.Value = 4000;
            app.FileTypeDropDownLabel = uilabel(app.UIFigure);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.UIFigure);
            app.FileTypeDropDown.Items = {'mat', 'adibin', 'other'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = 'mat';
            app.BinaryFormatEditFieldLabel = uilabel(app.UIFigure);
            app.BinaryFormatEditFieldLabel.Text = 'Binary Format';
            app.BinaryFormatEditField = uieditfield(app.UIFigure, 'text');
            app.BinaryFormatEditField.Editable = 'off';
            app.BinaryFormatEditField.Enable = 'off';
            app.BinaryFormatEditField.Value = 'single';
            app.ChannelStructureEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelStructureEditFieldLabel.Text = 'Channel Structure';
            app.ChannelStructureEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelStructureEditField.Value = 'bla;pfc;emg';
            app.OverlapLabel = uilabel(app.UIFigure);
            app.OverlapLabel.Text = 'Overlap (%)';
            app.OverlapEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverlapEditField.Limits = [0 90];
            app.OverlapEditField.ValueDisplayFormat = '%.0f';
            app.OverlapEditField.Editable = 'off';
            app.OverlapEditField.Enable = 'off';
            app.OverlapEditField.Value = 50;
            app.ChannelAnalyzedEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelAnalyzedEditFieldLabel.Text = 'Channel Analyzed';
            app.ChannelAnalyzedEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelAnalyzedEditField.Value = 'bla';
            app.DurationHrsLabel = uilabel(app.UIFigure);
            app.DurationHrsLabel.Text = 'Duration (Hrs)';
            app.Analysis_Duration = uieditfield(app.UIFigure, 'numeric');
            app.Analysis_Duration.Limits = [0 99];
            app.Analysis_Duration.ValueDisplayFormat = '%.0f';
            app.Analysis_Duration.Editable = 'off';
            app.Analysis_Duration.Enable = 'off';
            app.Analysis_Duration_2 = uieditfield(app.UIFigure, 'numeric');
            app.Analysis_Duration_2.Limits = [1 100];
            app.Analysis_Duration_2.ValueDisplayFormat = '%.0f';
            app.Analysis_Duration_2.Editable = 'off';
            app.Analysis_Duration_2.Enable = 'off';
            app.Analysis_Duration_2.Value = 12;
            app.NormFactorLabel = uilabel(app.UIFigure);
            app.NormFactorLabel.Text = 'Norm. Factor';
            app.NormFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.NormFactorEditField.ValueDisplayFormat = '%5.2f';
            app.NormFactorEditField.Editable = 'off';
            app.NormFactorEditField.Enable = 'off';
            app.NormFactorEditField.Value = 1;
            app.SetsamplerateCheckBox = uicheckbox(app.UIFigure);
            app.SetsamplerateCheckBox.Enable = 'off';
            app.SetsamplerateCheckBox.Text = 'Set samplerate';
            app.SetsamplerateCheckBox.Value = true;
            app.WholeFileCheckBox = uicheckbox(app.UIFigure);
            app.WholeFileCheckBox.Enable = 'off';
            app.WholeFileCheckBox.Text = 'Whole File';
            app.WholeFileCheckBox.Value = true;
            app.WindowTypeEditFieldLabel = uilabel(app.UIFigure);
            app.WindowTypeEditFieldLabel.Enable = 'off';
            app.WindowTypeEditFieldLabel.Text = 'Window Type';
            app.WindowTypeEditField = uieditfield(app.UIFigure, 'text');
            app.WindowTypeEditField.Editable = 'off';
            app.WindowTypeEditField.Enable = 'off';
            app.WindowTypeEditField.Value = 'hann';
        end
    end

    methods (Access = public)

        % Construct app
        function app = set_parameters(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end