classdef single_LFP_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        LoadPathEditField         matlab.ui.control.EditField
        PlotrawLFPButton          matlab.ui.control.Button
        GenerateSinetestButton    matlab.ui.control.Button
        FromLabel                 matlab.ui.control.Label
        FromEditField             matlab.ui.control.NumericEditField
        ToLabel                   matlab.ui.control.Label
        ToEditField               matlab.ui.control.NumericEditField
        PlotFilteredSignalButton  matlab.ui.control.Button
        HzLabel                   matlab.ui.control.Label
        ToLabel_2                 matlab.ui.control.Label
        ToEditField_2             matlab.ui.control.NumericEditField
        HzLabel_3                 matlab.ui.control.Label
        FromEditField_2Label      matlab.ui.control.Label
        FromEditField_2           matlab.ui.control.NumericEditField
        LOADCheckBox              matlab.ui.control.CheckBox
        FiletypeDropDownLabel     matlab.ui.control.Label
        FiletypeDropDown          matlab.ui.control.DropDown
        PlotAverPSDButton         matlab.ui.control.Button
        ToLabel_3                 matlab.ui.control.Label
        ToEditField_3             matlab.ui.control.NumericEditField
        HzLabel_5                 matlab.ui.control.Label
        FromEditField_3Label      matlab.ui.control.Label
        FromEditField_3           matlab.ui.control.NumericEditField
        FreqEditFieldLabel        matlab.ui.control.Label
        FreqEditField             matlab.ui.control.NumericEditField
        HzLabel_7                 matlab.ui.control.Label
        FFTanalysisCheckBox       matlab.ui.control.CheckBox
        PlotRawPSDButton          matlab.ui.control.Button
        ORLabel                   matlab.ui.control.Label
        PlotPowerAreaButton       matlab.ui.control.Button
        loadLamp                  matlab.ui.control.Lamp
        CLEARButton               matlab.ui.control.Button
        PlotPeakFreqButton        matlab.ui.control.Button
        PlotPeakPowerButton       matlab.ui.control.Button
        RemoveOutliersButton      matlab.ui.control.Button
        xMedianEditFieldLabel     matlab.ui.control.Label
        xMedianEditField          matlab.ui.control.NumericEditField
        StatusEditFieldLabel      matlab.ui.control.Label
        StatusEditField           matlab.ui.control.EditField
        PlotPowerRatioButton      matlab.ui.control.Button
        Band1EditFieldLabel       matlab.ui.control.Label
        Band1EditField            matlab.ui.control.NumericEditField
        Band2EditFieldLabel       matlab.ui.control.Label
        Band2EditField            matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel   matlab.ui.control.Label
        BandwidthEditField        matlab.ui.control.NumericEditField
        HzLabel_8                 matlab.ui.control.Label
        LoadPathButton            matlab.ui.control.Button
        PSDPROCESSINGLabel        matlab.ui.control.Label
        NormaliseButton           matlab.ui.control.Button
        RemoveNoiseButton         matlab.ui.control.Button
    end

    %properties shared between app components
    properties (Access = public)
        x %spectral_analysis_single object
        load_var = 0;
        psd_var = 0;
    end
    

    methods (Access = private)

        % Button pushed function: PlotrawLFPButton
        function PlotrawLFPButtonPushed(app, event)
            if app.load_var==1
                %plot LFP
                plot_raw_volt(app.x,app.x.input_wave,[0 0 0],1);
            else
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            end
            
        end

        % Button pushed function: GenerateSinetestButton
        function GenerateSinetestButtonPushed(app, event)
            %initialise object
            app.x = spectral_analysis_single;
            
            %create sine wave
            sine_gen(app.x,app.FreqEditField.Value,10) %10 min duration sine wave
            
            %plot 250 ms
            plot(app.UIAxes,app.x.input_wave(1:app.x.Fs/4))
            
            %edit the plot axes
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            
            %set load var to 1
            app.load_var = 1;
            
            %Enable get psd button
            app.FFTanalysisCheckBox.Enable = 'on';
            
            % display performed action
            app.StatusEditField.Value = ['A ' num2str(app.FreqEditField.Value) 'Hz Sine wave was generated'];
            
        end

        % Button pushed function: PlotFilteredSignalButton
        function PlotFilteredSignalButtonPushed(app, event)
            if app.load_var==1
                % filter LFP
                filtered_signal = app.x.BandPassFilter(app.x.input_wave,app.x.Fs, app.FromEditField.Value, app.ToEditField.Value);
                % plot LFP with filtered LFP
                hold on;
                plot_raw_volt(app.x,app.x.input_wave,[0 0 0],1);
                plot_raw_volt(app.x,filtered_signal, [1 0 0],1.5);
            else
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            end
        end

        % Value changed function: LOADCheckBox
        function LOADCheckBoxValueChanged(app, event)
            if app.LOADCheckBox.Value == 1 % check box value
                % initialise object
                app.x = spectral_analysis_single;
                % get path
                app.x.load_path = app.LoadPathEditField.Value;
                
                if strcmp(app.FiletypeDropDown.Value,'.mat') == 1
                    % load matlab file from labchart
                    load_labchart_mat(app.x);
                    
                elseif strcmp(app.FiletypeDropDown.Value,'.bin') == 1
                    % load binary file from labchart
                    load_labchart_bin(app.x);
                    
                elseif strcmp(app.FiletypeDropDown.Value,'pwrmat') == 1
                    % load analysed power matrix
                    load_p_matrix(app.x);
                    app.load_var = 1;
                    app.psd_var = 1;
                    % obtain psd paramteres
                    obtain_PSD_params(app.x, app.FromEditField_2.Value,app.ToEditField_2.Value)
                    app.FromEditField_3.Value = app.FromEditField_2.Value;
                    app.ToEditField_3.Value = app.ToEditField_2.Value;
                    
                    % print announcment
                    app.StatusEditField.Value = [app.x.exp_id ' was Loaded!'];
                    return
                end
                
                if isempty(app.x.input_wave)
                    return
                end
                % down sample file to 1000Hz
                down_sample (app.x);
                
                % set load var to 1
                app.load_var = 1;
                
                % Enable get psd button
                app.FFTanalysisCheckBox.Enable = 'on';
                
                % set lamp color to green
                app.loadLamp.Color = [0 1 0];
                
                % print announcment
                app.StatusEditField.Value = [app.x.exp_id ' was Loaded!'];
            else
                % clear x object
                app.x = [];
                % set load var to 0
                app.load_var = 0;
                % set lamp color to red
                app.loadLamp.Color = [1 0 0];
            end
            
        end

        % Button pushed function: PlotAverPSDButton
        function PlotAverPSDButtonPushed(app, event)
            if app.load_var==1 && app.psd_var==1
                plot_PSD(app.x,app.FromEditField_3.Value,app.ToEditField_3.Value,0);
            elseif app.load_var==0
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            elseif app.psd_var==0
                uialert(app.UIFigure,'PSD was not found!! Please use -FFT analysis-','Error.')
            end
        end

        % Value changed function: FFTanalysisCheckBox
        function FFTanalysisCheckBoxValueChanged(app, event)
            check_var = app.FFTanalysisCheckBox.Value;
            if check_var == 1
                if app.load_var==1
                    % get frequency limits for analysis
                    app.x.LowFCut = app.FromEditField_2.Value;
                    app.x.HighFCut = app.ToEditField_2.Value;
                    psd_only(app.x)
                    obtain_PSD_params(app.x, app.FromEditField_2.Value,app.ToEditField_2.Value)
                    app.FromEditField_3.Value = app.FromEditField_2.Value;
                    app.ToEditField_3.Value = app.ToEditField_2.Value;
                    
                    % display performed action
                    app.StatusEditField.Value = 'FFT analysis completed';
                else
                    uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
                end
                
                %set load var to 1
                app.psd_var = 1;
                
            else
                %set load var to 0
                app.psd_var = 0;
                
                %enable remove outliers
                app.RemoveOutliersButton.Enable = 'on';
                
            end
        end

        % Button pushed function: PlotRawPSDButton
        function PlotRawPSDButtonPushed(app, event)
            if app.load_var==1 && app.psd_var==1
                plot_PSD(app.x,app.FromEditField_3.Value,app.ToEditField_3.Value,1)
            elseif app.load_var==0
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            elseif app.psd_var==0
                uialert(app.UIFigure,'PSD was not found!! Please use -Get Raw PSD-','Error.')
            end
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            %clear all settings
            app.x = [];
            app.load_var = 0;
            app.psd_var = 0;
            app.LOADCheckBox.Value = 0;
            app.FFTanalysisCheckBox.Value = 0;
            app.FFTanalysisCheckBox.Enable = 'off';
            app.RemoveOutliersButton.Enable = 'on';
            app.loadLamp.Color = [1 0 0];
            app.StatusEditField.Value = 'Please load LFP file or generate sine wave';
            cla(app.UIAxes);
        end

        % Button pushed function: PlotPeakPowerButton
        function PlotPeakPowerButtonPushed(app, event)
            if app.load_var==1 && app.psd_var==1
                plot_PSD_params(app.x,'peak_power')
            elseif app.load_var==0
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            elseif app.psd_var==0
                uialert(app.UIFigure,'PSD was not found!! Please use -FFT analysis-','Error.')
            end
        end

        % Button pushed function: PlotPeakFreqButton
        function PlotPeakFreqButtonPushed(app, event)
            if app.load_var==1 && app.psd_var==1
                plot_PSD_params(app.x,'peak_freq')
            elseif app.load_var==0
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            elseif app.psd_var==0
                uialert(app.UIFigure,'PSD was not found!! Please use -FFT analysis-','Error.')
            end
        end

        % Button pushed function: PlotPowerAreaButton
        function PlotPowerAreaButtonPushed(app, event)
            if app.load_var==1 && app.psd_var==1
                plot_PSD_params(app.x,'power_area')
            elseif app.load_var==0
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            elseif app.psd_var==0
                uialert(app.UIFigure,'PSD was not found!! Please use -FFT analysis-','Error.')
            end
        end

        % Button pushed function: RemoveOutliersButton
        function RemoveOutliersButtonPushed(app, event)
            if app.load_var==1 && app.psd_var==1
                remove_outliers(app.x,app.xMedianEditField.Value)
                remove_outliers_psd(app.x)
                app.RemoveOutliersButton.Enable = 'off';
            elseif app.load_var==0
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            elseif app.psd_var==0
                uialert(app.UIFigure,'PSD was not found!! Please use -FFT analysis-','Error.')
            end
        end

        % Button pushed function: PlotPowerRatioButton
        function PlotPowerRatioButtonPushed(app, event)
            if app.load_var==1 && app.psd_var==1
                plot_power_ratio(app.x,app.Band1EditField.Value, app.Band2EditField.Value,  app.BandwidthEditField.Value)
            elseif app.load_var==0
                uialert(app.UIFigure,'File was not found!! Please load LFP file or generate sine wave','Error.')
            elseif app.psd_var==0
                uialert(app.UIFigure,'PSD was not found!! Please use -FFT analysis-','Error.')
            end
        end

        % Button pushed function: LoadPathButton
        function LoadPathButtonPushed(app, event)
            % get folder interactively
            desktop_path = cd;
            desktop_path = desktop_path(1:strfind(desktop_path,'Documents')-1);
            desktop_path = fullfile(desktop_path,'Desktop\');
            temp_path = uigetdir(desktop_path);
            app.LoadPathEditField.Value ;
            if isempty(temp_path)==0
                app.LoadPathEditField.Value = [temp_path '\'];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Figure Gen')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LoadPathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadPathEditField.Value = 'C:\Users\panton01\Desktop\Ex_vivo_data\raw_data\WIN_BLA\';
            app.PlotrawLFPButton = uibutton(app.UIFigure, 'push');
            app.PlotrawLFPButton.ButtonPushedFcn = createCallbackFcn(app, @PlotrawLFPButtonPushed, true);
            app.PlotrawLFPButton.Text = 'Plot raw LFP';
            app.GenerateSinetestButton = uibutton(app.UIFigure, 'push');
            app.GenerateSinetestButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSinetestButtonPushed, true);
            app.GenerateSinetestButton.Text = 'Generate Sine test';
            app.FromLabel = uilabel(app.UIFigure);
            app.FromLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField.Limits = [0 10000];
            app.FromEditField.Value = 0.4;
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField.Limits = [0 10000];
            app.ToEditField.Value = 200;
            app.PlotFilteredSignalButton = uibutton(app.UIFigure, 'push');
            app.PlotFilteredSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFilteredSignalButtonPushed, true);
            app.PlotFilteredSignalButton.Text = 'Plot Filtered Signal';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.ToLabel_2 = uilabel(app.UIFigure);
            app.ToLabel_2.Text = 'To:';
            app.ToEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField_2.Limits = [0 1000];
            app.ToEditField_2.Value = 200;
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = 'Hz';
            app.FromEditField_2Label = uilabel(app.UIFigure);
            app.FromEditField_2Label.Text = 'From:';
            app.FromEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField_2.Limits = [0 1000];
            app.FromEditField_2.Value = 0.4;
            app.LOADCheckBox = uicheckbox(app.UIFigure);
            app.LOADCheckBox.ValueChangedFcn = createCallbackFcn(app, @LOADCheckBoxValueChanged, true);
            app.LOADCheckBox.Text = 'LOAD ';
            app.FiletypeDropDownLabel = uilabel(app.UIFigure);
            app.FiletypeDropDownLabel.Text = 'File type';
            app.FiletypeDropDown = uidropdown(app.UIFigure);
            app.FiletypeDropDown.Items = {'.mat', '.bin', 'pwrmat'};
            app.FiletypeDropDown.Value = '.mat';
            app.PlotAverPSDButton = uibutton(app.UIFigure, 'push');
            app.PlotAverPSDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAverPSDButtonPushed, true);
            app.PlotAverPSDButton.Text = 'Plot Aver PSD';
            app.ToLabel_3 = uilabel(app.UIFigure);
            app.ToLabel_3.Text = 'To:';
            app.ToEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField_3.Limits = [0.4 200];
            app.ToEditField_3.Value = 200;
            app.HzLabel_5 = uilabel(app.UIFigure);
            app.HzLabel_5.Text = 'Hz';
            app.FromEditField_3Label = uilabel(app.UIFigure);
            app.FromEditField_3Label.Text = 'From:';
            app.FromEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField_3.Limits = [0.4 200];
            app.FromEditField_3.Value = 0.4;
            app.FreqEditFieldLabel = uilabel(app.UIFigure);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqEditField.Value = 40;
            app.HzLabel_7 = uilabel(app.UIFigure);
            app.HzLabel_7.Text = 'Hz';
            app.FFTanalysisCheckBox = uicheckbox(app.UIFigure);
            app.FFTanalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @FFTanalysisCheckBoxValueChanged, true);
            app.FFTanalysisCheckBox.Enable = 'off';
            app.FFTanalysisCheckBox.Text = 'FFT analysis';
            app.PlotRawPSDButton = uibutton(app.UIFigure, 'push');
            app.PlotRawPSDButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawPSDButtonPushed, true);
            app.PlotRawPSDButton.Text = 'Plot Raw PSD';
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.PlotPowerAreaButton = uibutton(app.UIFigure, 'push');
            app.PlotPowerAreaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPowerAreaButtonPushed, true);
            app.PlotPowerAreaButton.Text = 'Plot Power Area';
            app.loadLamp = uilamp(app.UIFigure);
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.PlotPeakFreqButton = uibutton(app.UIFigure, 'push');
            app.PlotPeakFreqButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPeakFreqButtonPushed, true);
            app.PlotPeakFreqButton.Text = 'Plot Peak Freq.';
            app.PlotPeakPowerButton = uibutton(app.UIFigure, 'push');
            app.PlotPeakPowerButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPeakPowerButtonPushed, true);
            app.PlotPeakPowerButton.Text = 'Plot Peak Power';
            app.RemoveOutliersButton = uibutton(app.UIFigure, 'push');
            app.RemoveOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOutliersButtonPushed, true);
            app.RemoveOutliersButton.Text = 'Remove Outliers';
            app.xMedianEditFieldLabel = uilabel(app.UIFigure);
            app.xMedianEditFieldLabel.Text = 'x Median';
            app.xMedianEditField = uieditfield(app.UIFigure, 'numeric');
            app.xMedianEditField.Limits = [0 100];
            app.xMedianEditField.Value = 5;
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Value = 'Please load LFP file or generate sine wave';
            app.PlotPowerRatioButton = uibutton(app.UIFigure, 'push');
            app.PlotPowerRatioButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPowerRatioButtonPushed, true);
            app.PlotPowerRatioButton.Text = 'Plot Power Ratio';
            app.Band1EditFieldLabel = uilabel(app.UIFigure);
            app.Band1EditFieldLabel.Text = 'Band (1)';
            app.Band1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Band1EditField.Limits = [0.4 200];
            app.Band1EditField.Value = 5;
            app.Band2EditFieldLabel = uilabel(app.UIFigure);
            app.Band2EditFieldLabel.Text = 'Band (2)';
            app.Band2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Band2EditField.Limits = [0.4 200];
            app.Band2EditField.Value = 10;
            app.BandwidthEditFieldLabel = uilabel(app.UIFigure);
            app.BandwidthEditFieldLabel.Text = 'Band width';
            app.BandwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandwidthEditField.Limits = [0.4 200];
            app.BandwidthEditField.Value = 4;
            app.HzLabel_8 = uilabel(app.UIFigure);
            app.HzLabel_8.Text = 'Hz';
            app.LoadPathButton = uibutton(app.UIFigure, 'push');
            app.LoadPathButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPathButtonPushed, true);
            app.LoadPathButton.Text = 'Load Path';
            app.PSDPROCESSINGLabel = uilabel(app.UIFigure);
            app.PSDPROCESSINGLabel.Text = 'PSD PROCESSING';
            app.NormaliseButton = uibutton(app.UIFigure, 'push');
            app.NormaliseButton.Text = 'Normalise';
            app.RemoveNoiseButton = uibutton(app.UIFigure, 'push');
            app.RemoveNoiseButton.Text = 'Remove Noise';
        end
    end

    methods (Access = public)

        % Construct app
        function app = single_LFP_analysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end